classdef mopt3d_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        istepEditFieldLabel           matlab.ui.control.Label
        istepEditField                matlab.ui.control.NumericEditField
        spaceEditFieldLabel           matlab.ui.control.Label
        spaceEditField                matlab.ui.control.NumericEditField
        nsesEditFieldLabel            matlab.ui.control.Label
        nsesEditField                 matlab.ui.control.NumericEditField
        modeltypeEditFieldLabel       matlab.ui.control.Label
        modeltypeEditField            matlab.ui.control.NumericEditField
        idataEditFieldLabel           matlab.ui.control.Label
        idataEditField                matlab.ui.control.NumericEditField
        data_typeEditFieldLabel       matlab.ui.control.Label
        data_typeEditField            matlab.ui.control.NumericEditField
        data_folEditFieldLabel        matlab.ui.control.Label
        data_folEditField             matlab.ui.control.EditField
        mtg_sufEditFieldLabel         matlab.ui.control.Label
        mtg_sufEditField              matlab.ui.control.EditField
        tol_sufEditFieldLabel         matlab.ui.control.Label
        tol_sufEditField              matlab.ui.control.EditField
        compute_blocksEditFieldLabel  matlab.ui.control.Label
        compute_blocksEditField       matlab.ui.control.NumericEditField
        freq_flashEditFieldLabel      matlab.ui.control.Label
        freq_flashEditField           matlab.ui.control.NumericEditField
        wave_numEditFieldLabel        matlab.ui.control.Label
        wave_numEditField             matlab.ui.control.NumericEditField
        slow_or_fastEditFieldLabel    matlab.ui.control.Label
        slow_or_fastEditField         matlab.ui.control.NumericEditField
        RetinotopyAnalysisLabel       matlab.ui.control.Label
        TraditionalAnalysisLabel      matlab.ui.control.Label
        timesEditFieldLabel           matlab.ui.control.Label
        timesEditField                matlab.ui.control.NumericEditField
        statEditFieldLabel            matlab.ui.control.Label
        statEditField                 matlab.ui.control.NumericEditField
        poolEditFieldLabel            matlab.ui.control.Label
        poolEditField                 matlab.ui.control.NumericEditField
        smoothEditFieldLabel          matlab.ui.control.Label
        smoothEditField               matlab.ui.control.NumericEditField
        n_condEditFieldLabel          matlab.ui.control.Label
        n_condEditField               matlab.ui.control.NumericEditField
        baselineEditFieldLabel        matlab.ui.control.Label
        baselineEditField             matlab.ui.control.EditField
        freq_spinEditFieldLabel       matlab.ui.control.Label
        freq_spinEditField            matlab.ui.control.EditField
        eccenEditFieldLabel           matlab.ui.control.Label
        eccenEditField                matlab.ui.control.NumericEditField
        saveanalEditFieldLabel        matlab.ui.control.Label
        saveanalEditField             matlab.ui.control.NumericEditField
        AdditionalSettingsLabel       matlab.ui.control.Label
        kwaveEditFieldLabel           matlab.ui.control.Label
        kwaveEditField                matlab.ui.control.NumericEditField
        dstcritEditFieldLabel         matlab.ui.control.Label
        dstcritEditField              matlab.ui.control.NumericEditField
        dstcrit2EditFieldLabel        matlab.ui.control.Label
        dstcrit2EditField             matlab.ui.control.NumericEditField
        abscoefEditFieldLabel         matlab.ui.control.Label
        abscoefEditField              matlab.ui.control.NumericEditField
        scatcoefEditFieldLabel        matlab.ui.control.Label
        scatcoefEditField             matlab.ui.control.NumericEditField
        resolEditFieldLabel           matlab.ui.control.Label
        resolEditField                matlab.ui.control.NumericEditField
        errcritEditFieldLabel         matlab.ui.control.Label
        errcritEditField              matlab.ui.control.NumericEditField
        epsihbo2EditFieldLabel        matlab.ui.control.Label
        epsihbo2EditField             matlab.ui.control.NumericEditField
        epsihbo1EditFieldLabel        matlab.ui.control.Label
        epsihbo1EditField             matlab.ui.control.NumericEditField
        epsihb2EditFieldLabel         matlab.ui.control.Label
        epsihb2EditField              matlab.ui.control.NumericEditField
        epsihb1EditFieldLabel         matlab.ui.control.Label
        epsihb1EditField              matlab.ui.control.NumericEditField
        PlotSettingsLabel             matlab.ui.control.Label
        plot_smoothCheckBox           matlab.ui.control.CheckBox
        bananCheckBox                 matlab.ui.control.CheckBox
        scalpCheckBox                 matlab.ui.control.CheckBox
        brainCheckBox                 matlab.ui.control.CheckBox
        isectCheckBox                 matlab.ui.control.CheckBox
        sourcedetCheckBox             matlab.ui.control.CheckBox
        savemeanCheckBox              matlab.ui.control.CheckBox
        brainresEditFieldLabel        matlab.ui.control.Label
        brainresEditField             matlab.ui.control.NumericEditField
        outresEditFieldLabel          matlab.ui.control.Label
        outresEditField               matlab.ui.control.NumericEditField
        nsubjEditFieldLabel           matlab.ui.control.Label
        nsubjEditField                matlab.ui.control.NumericEditField
        expEditFieldLabel             matlab.ui.control.Label
        expEditField                  matlab.ui.control.EditField
        StartButton                   matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        SubjectsLabel                 matlab.ui.control.Label
        UITable_sub                   matlab.ui.control.Table
        pathEditFieldLabel            matlab.ui.control.Label
        pathEditField                 matlab.ui.control.EditField
        UITable_date                  matlab.ui.control.Table
        dpf2EditField_2Label          matlab.ui.control.Label
        dpf2EditField                 matlab.ui.control.NumericEditField
        dpf1EditFieldLabel            matlab.ui.control.Label
        dpf1EditField                 matlab.ui.control.NumericEditField
        meansumEditFieldLabel         matlab.ui.control.Label
        meansumEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        %% these variables don't actually do anything, values are defined below in the GUI
        nsubj = 1;
        exp = 'rop';
        sub = {'0503';'0533';'0757';'0789';'0790';'0803';'0808';'1501';'1502';'1503';'1504';'1505';'1506';'1507';'1508';'1509';'1510';'1511';'1512';'1513';'1515';'1516';'1519';'1520';'1521';'1522';'1523';'1524';'1525';'1526';'1527';'1528';'1529';'1530';'1531';'1532';'1534';'1536';'1537';}; %
        date = {'060813';'052013';'121013';'111413';'022514';'101413';'083013';'061013';'072413';'062713';'093013';'072213';'062713';'083013';'062613';'061713';'082613';'072913';'083013';'090913';'091413';'072913';'100713';'060813';'060813';'083013';'102113';'091413';'091413';'070213';'090913';'102113';'091413';'092313';'093013';'101413';'112613';'102513';'102113'}; %
        path = ['/Volumes/Lab_Files/Data/Backup_Illinois'];
        istep = 2.5 %resolution of voxels
        space = 2; %talaraich (tol) = 1; real (rol) = 2; 
        data_type = 1; %1 - ppod .001 unepoched; 2 - avm average files
        data_fol = 'pc00-00'; %folder that the .001 or .avm files are in
        mtg_suf = '_6col'; % abc1234xxxxx.mtg
        tol_suf = '_scalp_dig_fid'; % abc1234xxxxx.rol/tol
        compute_blocks = 3; %for loading data and analysis  %since blocks go 1234 PolarNPolarPEccenNEccenP
        freq_spin = 1/48; % (wedge spin frequency Hz)
        freq_flash = 5; % (Wedge alternation freq Hz)
        wave_num = 12; %wavelength length of flash frequency for wavelets 
        slow_or_fast = 2; %1 slow, 2 fast, 1:2 slow and fast %this is just for the plotting retiontopy now (combine with prm.idata)
        baseline = 1:6; %inclusive (.avm file says 0 is point 7)
        times = 10; %times to plot the result
        stat = 1; %1-Means; 2-Std; 3-Nsub; 4-ttest; 5-z(tanal)
        pool = 1; %pooled (1) or unpooled (0) standard deviation
        smooth = 8; %0 - no smoothing, > 0 is smoothing gaussian SD in mm
        n_cond = 4; %number of bins in .avm files %can get from the header
        eccen = 2; %eccentricity
        saveanal = 0; %1 save analysis, 0 don't save
        nses = 1; %opa = 8, %rop = 1, %emm = 4?, %milk = 1  %can I automate this?
        modeltype = 1; %1 - Geometric; 2 - Physical; 3 - Inverse
        idata = 2; %type of data, 1 if dc, 2 if phase, 3 if ac 
        kwave = 1; %Wavelength to use (1 = All; 2 = short; 3 = long; 4 = oxy; 5 = deoxy);
        dstcrit = 2.00; %shortest distance channel to consider in cm
        dstcrit2 = 6.0; %longest distance 
        abscoef = .01;
        scatcoef = 1; 
        resol = .8; %path width (in cm);
        errcrit = 160; %SD of phase - 160-200 normal %make option for short and long 
        epsihbo2=0.956;%g Hbo830% absorption coeficients for Hb and HbO (from ISS 'extinc.txt')
        epsihbo1=2.3153;%f Hbo690
        epsihb2=4.9307;%e  Hb830
        epsihb1=1.7914;%d Hb690
        dpf1 = 6.51; %Differential path length factor [690 830] per Duncan et al., 95
        dpf2 = 5.86; 
        plot_smooth = 0; %1 if want smoothing; 0 if full resolution brain for plotting %take this out now that we downsample brain to do this part
        banan = 1; %bananas for subjects
        scalp = 1; %plot scalp for subjects
        brain = 1;  %plot brain for subjects
        isect = 1;  %plot banana+brain intersection for each subject
        sourcedet = 1; %plot sources and detectors for subjects
        savemean = 0;  %save a mean bananas over subjects brik, 1 yes 0 no
        meansum = 1; %1 = plot mean bananas over subjects; 2 = plot sum of bananas over subjects
        brainres = 2.5; %resolution of plotting brain %need to add functionality
        outres = .5; %for freesurfer .nii file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tsub = {'0503';'0533';'0757';'0789';'0790';'0803';'0808';'1501';'1502';'1503';'1504';'1505';'1506';'1507';'1508';'1509';'1510';'1511';'1512';'1513';'1515';'1516';'1519';'1520';'1521';'1522';'1523';'1524';'1525';'1526';'1527';'1528';'1529';'1530';'1531';'1532';'1534';'1536';'1537'};
            tdate = {'060813';'052013';'121013';'111413';'022514';'101413';'083013';'061013';'072413';'062713';'093013';'072213';'062713';'083013';'062613';'061713';'082613';'072913';'083013';'090913';'091413';'072913';'100713';'060813';'060813';'083013';'102113';'091413';'091413';'070213';'090913';'102113';'091413';'092313';'093013';'101413';'112613';'102513';'102113'}; %
            app.UITable_sub.Data = tsub;
            app.UITable_date.Data = tdate;
        end

        % Callback function: StartButton, istepEditField
        function StartButtonPushed(app, event)
            app.nsubj = app.nsubjEditField.Value;
            app.exp = app.expEditField.Value;
            app.path = app.pathEditField.Value;
            app.istep = app.istepEditField.Value;
            app.space = app.spaceEditField.Value;
            app.data_type = app.data_typeEditField.Value;
            app.data_fol = app.data_folEditField.Value;
            app.mtg_suf = app.mtg_sufEditField.Value;
            app.tol_suf = app.tol_sufEditField.Value;
            app.compute_blocks = app.compute_blocksEditField.Value;
            app.freq_spin = app.freq_spinEditField.Value;
            app.freq_flash = app.freq_flashEditField.Value;
            app.wave_num = app.wave_numEditField.Value;
            app.slow_or_fast = app.slow_or_fastEditField.Value;
            app.baseline = app.baselineEditField.Value;
            app.times = app.timesEditField.Value;
            app.stat = app.statEditField.Value;
            app.pool = app.poolEditField.Value;
            app.smooth = app.smoothEditField.Value;
            app.n_cond = app.n_condEditField.Value;
            app.eccen = app.eccenEditField.Value;
            app.saveanal = app.saveanalEditField.Value;
            app.nses = app.nsesEditField.Value;
            app.modeltype = app.modeltypeEditField.Value;
            app.idata = app.idataEditField.Value;
            app.kwave = app.kwaveEditField.Value;
            app.dstcrit = app.dstcritEditField.Value;
            app.dstcrit2 = app.dstcrit2EditField.Value;
            app.abscoef = app.abscoefEditField.Value;
            app.scatcoef = app.scatcoefEditField.Value;
            app.resol = app.resolEditField.Value;
            app.errcrit = app.errcritEditField.Value;
            app.epsihbo2 = app.epsihbo2EditField.Value;
            app.epsihbo1 = app.epsihbo1EditField.Value;
            app.epsihb2 = app.epsihb2EditField.Value;
            app.epsihb1 = app.epsihb1EditField.Value;
            app.dpf2 = app.dpf2EditField.Value;
            app.dpf1 = app.dpf1EditField.Value;
            app.plot_smooth = app.plot_smoothCheckBox.Value;
            app.banan = app.bananCheckBox.Value;
            app.scalp = app.scalpCheckBox.Value;
            app.brain = app.brainCheckBox.Value;
            app.isect = app.isectCheckBox.Value;
            app.sourcedet = app.sourcedetCheckBox.Value;
            app.savemean = app.savemeanCheckBox.Value;
            app.meansum = app.meansumEditField.Value;
            app.brainres = app.brainresEditField.Value;
            app.outres = app.outresEditField.Value;      
            
            app.sub = app.UITable_sub.Data;
            app.date = app.UITable_date.Data;
            
            mopt3d;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.istepEditFieldLabel = uilabel(app.GridLayout);
            app.istepEditFieldLabel.Text = 'istep';
            app.istepEditField = uieditfield(app.GridLayout, 'numeric');
            app.istepEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.istepEditField.Tooltip = {'resolution of voxels'};
            app.istepEditField.Value = 2.5;
            app.spaceEditFieldLabel = uilabel(app.GridLayout);
            app.spaceEditFieldLabel.Text = 'space';
            app.spaceEditField = uieditfield(app.GridLayout, 'numeric');
            app.spaceEditField.Tooltip = {'talaraich (tol) = 1; real (rol) = 2; '};
            app.spaceEditField.Value = 2;
            app.nsesEditFieldLabel = uilabel(app.GridLayout);
            app.nsesEditFieldLabel.Text = 'nses';
            app.nsesEditField = uieditfield(app.GridLayout, 'numeric');
            app.nsesEditField.Tooltip = {'Number of sessions: %opa = 8, %rop = 1, %emm = 4?, %milk = 1 '};
            app.nsesEditField.Value = 1;
            app.modeltypeEditFieldLabel = uilabel(app.GridLayout);
            app.modeltypeEditFieldLabel.Text = 'modeltype';
            app.modeltypeEditField = uieditfield(app.GridLayout, 'numeric');
            app.modeltypeEditField.Tooltip = {'1 - Geometric; 2 - Physical; 3 - Inverse'};
            app.modeltypeEditField.Value = 1;
            app.idataEditFieldLabel = uilabel(app.GridLayout);
            app.idataEditFieldLabel.Text = 'idata';
            app.idataEditField = uieditfield(app.GridLayout, 'numeric');
            app.idataEditField.Tooltip = {'type of data, 1 if dc, 2 if phase, 3 if ac '};
            app.idataEditField.Value = 2;
            app.data_typeEditFieldLabel = uilabel(app.GridLayout);
            app.data_typeEditFieldLabel.Text = 'data_type';
            app.data_typeEditField = uieditfield(app.GridLayout, 'numeric');
            app.data_typeEditField.Tooltip = {'1 - ppod .001 unepoched; 2 - avm average files'};
            app.data_typeEditField.Value = 1;
            app.data_folEditFieldLabel = uilabel(app.GridLayout);
            app.data_folEditFieldLabel.Text = 'data_fol';
            app.data_folEditField = uieditfield(app.GridLayout, 'text');
            app.data_folEditField.Tooltip = {'folder that the .001 or .avm files are in'};
            app.data_folEditField.Value = 'pc00-00';
            app.mtg_sufEditFieldLabel = uilabel(app.GridLayout);
            app.mtg_sufEditFieldLabel.Text = 'mtg_suf';
            app.mtg_sufEditField = uieditfield(app.GridLayout, 'text');
            app.mtg_sufEditField.Tooltip = {'abc1234xxxxx.mtg'};
            app.mtg_sufEditField.Value = '_6col';
            app.tol_sufEditFieldLabel = uilabel(app.GridLayout);
            app.tol_sufEditFieldLabel.Text = 'tol_suf';
            app.tol_sufEditField = uieditfield(app.GridLayout, 'text');
            app.tol_sufEditField.Tooltip = {'abc1234xxxxx.rol/tol'};
            app.tol_sufEditField.Value = '_scalp_dig_fid';
            app.compute_blocksEditFieldLabel = uilabel(app.GridLayout);
            app.compute_blocksEditFieldLabel.Text = 'compute_blocks';
            app.compute_blocksEditField = uieditfield(app.GridLayout, 'numeric');
            app.compute_blocksEditField.Tooltip = {'for loading data and analysis  %since blocks go 1234 PolarNPolarPEccenNEccenP'};
            app.compute_blocksEditField.Value = 3;
            app.freq_flashEditFieldLabel = uilabel(app.GridLayout);
            app.freq_flashEditFieldLabel.Text = 'freq_flash';
            app.freq_flashEditField = uieditfield(app.GridLayout, 'numeric');
            app.freq_flashEditField.Tooltip = {'(Wedge alternation freq Hz)'};
            app.freq_flashEditField.Value = 5;
            app.wave_numEditFieldLabel = uilabel(app.GridLayout);
            app.wave_numEditFieldLabel.Text = 'wave_num';
            app.wave_numEditField = uieditfield(app.GridLayout, 'numeric');
            app.wave_numEditField.Tooltip = {'wavelength length of flash frequency for wavelets '};
            app.wave_numEditField.Value = 12;
            app.slow_or_fastEditFieldLabel = uilabel(app.GridLayout);
            app.slow_or_fastEditFieldLabel.Text = 'slow_or_fast';
            app.slow_or_fastEditField = uieditfield(app.GridLayout, 'numeric');
            app.slow_or_fastEditField.Tooltip = {'1 slow, 2 fast, 1:2 slow and fast %this is just for the plotting retiontopy now (combine with prm.idata)'};
            app.slow_or_fastEditField.Value = 2;
            app.RetinotopyAnalysisLabel = uilabel(app.GridLayout);
            app.RetinotopyAnalysisLabel.Text = 'Retinotopy Analysis';
            app.TraditionalAnalysisLabel = uilabel(app.GridLayout);
            app.TraditionalAnalysisLabel.Text = 'Traditional Analysis';
            app.timesEditFieldLabel = uilabel(app.GridLayout);
            app.timesEditFieldLabel.Text = 'times';
            app.timesEditField = uieditfield(app.GridLayout, 'numeric');
            app.timesEditField.Tooltip = {'times to plot the result'};
            app.timesEditField.Value = 10;
            app.statEditFieldLabel = uilabel(app.GridLayout);
            app.statEditFieldLabel.Text = 'stat';
            app.statEditField = uieditfield(app.GridLayout, 'numeric');
            app.statEditField.Tooltip = {'1-Means; 2-Std; 3-Nsub; 4-ttest; 5-z(tanal)'};
            app.statEditField.Value = 1;
            app.poolEditFieldLabel = uilabel(app.GridLayout);
            app.poolEditFieldLabel.Text = 'pool';
            app.poolEditField = uieditfield(app.GridLayout, 'numeric');
            app.poolEditField.Tooltip = {'pooled (1) or unpooled (0) standard deviation'};
            app.poolEditField.Value = 1;
            app.smoothEditFieldLabel = uilabel(app.GridLayout);
            app.smoothEditFieldLabel.Text = 'smooth';
            app.smoothEditField = uieditfield(app.GridLayout, 'numeric');
            app.smoothEditField.Tooltip = {'0 - no smoothing, > 0 is smoothing gaussian SD in mm'};
            app.smoothEditField.Value = 8;
            app.n_condEditFieldLabel = uilabel(app.GridLayout);
            app.n_condEditFieldLabel.Text = 'n_cond';
            app.n_condEditField = uieditfield(app.GridLayout, 'numeric');
            app.n_condEditField.Tooltip = {'number of bins in .avm files %can get from the header'};
            app.n_condEditField.Value = 4;
            app.baselineEditFieldLabel = uilabel(app.GridLayout);
            app.baselineEditFieldLabel.Text = 'baseline';
            app.baselineEditField = uieditfield(app.GridLayout, 'text');
            app.baselineEditField.Tooltip = {'inclusive (.avm file says 0 is point 7)'};
            app.baselineEditField.Value = '1:6';
            app.freq_spinEditFieldLabel = uilabel(app.GridLayout);
            app.freq_spinEditFieldLabel.Text = 'freq_spin';
            app.freq_spinEditField = uieditfield(app.GridLayout, 'text');
            app.freq_spinEditField.Tooltip = {'(wedge spin frequency Hz)'};
            app.freq_spinEditField.Value = '1/48';
            app.eccenEditFieldLabel = uilabel(app.GridLayout);
            app.eccenEditFieldLabel.Text = 'eccen';
            app.eccenEditField = uieditfield(app.GridLayout, 'numeric');
            app.eccenEditField.Tooltip = {'eccentricity'};
            app.eccenEditField.Value = 2;
            app.saveanalEditFieldLabel = uilabel(app.GridLayout);
            app.saveanalEditFieldLabel.Text = 'saveanal';
            app.saveanalEditField = uieditfield(app.GridLayout, 'numeric');
            app.saveanalEditField.Tooltip = {'1 save analysis, 0 don''t save'};
            app.AdditionalSettingsLabel = uilabel(app.GridLayout);
            app.AdditionalSettingsLabel.Text = 'Additional Settings';
            app.kwaveEditFieldLabel = uilabel(app.GridLayout);
            app.kwaveEditFieldLabel.Text = 'kwave';
            app.kwaveEditField = uieditfield(app.GridLayout, 'numeric');
            app.kwaveEditField.Tooltip = {'Wavelength to use (1 = All; 2 = short; 3 = long; 4 = oxy; 5 = deoxy)'};
            app.kwaveEditField.Value = 1;
            app.dstcritEditFieldLabel = uilabel(app.GridLayout);
            app.dstcritEditFieldLabel.Text = 'dstcrit';
            app.dstcritEditField = uieditfield(app.GridLayout, 'numeric');
            app.dstcritEditField.ValueDisplayFormat = '%.2f';
            app.dstcritEditField.Tooltip = {'shortest distance channel to consider in cm'};
            app.dstcritEditField.Value = 2;
            app.dstcrit2EditFieldLabel = uilabel(app.GridLayout);
            app.dstcrit2EditFieldLabel.Text = 'dstcrit2';
            app.dstcrit2EditField = uieditfield(app.GridLayout, 'numeric');
            app.dstcrit2EditField.ValueDisplayFormat = '%.2f';
            app.dstcrit2EditField.Tooltip = {'longest distance '};
            app.dstcrit2EditField.Value = 6;
            app.abscoefEditFieldLabel = uilabel(app.GridLayout);
            app.abscoefEditFieldLabel.Text = 'abscoef';
            app.abscoefEditField = uieditfield(app.GridLayout, 'numeric');
            app.abscoefEditField.ValueDisplayFormat = '%.2f';
            app.abscoefEditField.Tooltip = {'absorption coefficient'; ''};
            app.abscoefEditField.Value = 0.01;
            app.scatcoefEditFieldLabel = uilabel(app.GridLayout);
            app.scatcoefEditFieldLabel.Text = 'scatcoef';
            app.scatcoefEditField = uieditfield(app.GridLayout, 'numeric');
            app.scatcoefEditField.Tooltip = {'scattering coefficient'};
            app.scatcoefEditField.Value = 1;
            app.resolEditFieldLabel = uilabel(app.GridLayout);
            app.resolEditFieldLabel.Text = 'resol';
            app.resolEditField = uieditfield(app.GridLayout, 'numeric');
            app.resolEditField.ValueDisplayFormat = '%.2f';
            app.resolEditField.Tooltip = {'path width (in cm)'};
            app.resolEditField.Value = 0.8;
            app.errcritEditFieldLabel = uilabel(app.GridLayout);
            app.errcritEditFieldLabel.Text = 'errcrit';
            app.errcritEditField = uieditfield(app.GridLayout, 'numeric');
            app.errcritEditField.Tooltip = {'SD of phase - 160-200 normal, KM - make option for short and long '};
            app.errcritEditField.Value = 160;
            app.epsihbo2EditFieldLabel = uilabel(app.GridLayout);
            app.epsihbo2EditFieldLabel.Text = 'epsihbo2';
            app.epsihbo2EditField = uieditfield(app.GridLayout, 'numeric');
            app.epsihbo2EditField.ValueDisplayFormat = '%.4f';
            app.epsihbo2EditField.Tooltip = {'Hbo830% absorption coeficients for Hb and HbO (from ISS ''extinc.txt'')'};
            app.epsihbo2EditField.Value = 0.956;
            app.epsihbo1EditFieldLabel = uilabel(app.GridLayout);
            app.epsihbo1EditFieldLabel.Text = 'epsihbo1';
            app.epsihbo1EditField = uieditfield(app.GridLayout, 'numeric');
            app.epsihbo1EditField.ValueDisplayFormat = '%.4f';
            app.epsihbo1EditField.Tooltip = {'Hbo690'};
            app.epsihbo1EditField.Value = 2.3153;
            app.epsihb2EditFieldLabel = uilabel(app.GridLayout);
            app.epsihb2EditFieldLabel.Text = 'epsihb2';
            app.epsihb2EditField = uieditfield(app.GridLayout, 'numeric');
            app.epsihb2EditField.ValueDisplayFormat = '%.4f';
            app.epsihb2EditField.Tooltip = {'Hb830'};
            app.epsihb2EditField.Value = 4.9307;
            app.epsihb1EditFieldLabel = uilabel(app.GridLayout);
            app.epsihb1EditFieldLabel.Text = 'epsihb1';
            app.epsihb1EditField = uieditfield(app.GridLayout, 'numeric');
            app.epsihb1EditField.ValueDisplayFormat = '%.4f';
            app.epsihb1EditField.Tooltip = {'Hb690'};
            app.epsihb1EditField.Value = 1.7914;
            app.PlotSettingsLabel = uilabel(app.GridLayout);
            app.PlotSettingsLabel.Text = 'Plot Settings';
            app.plot_smoothCheckBox = uicheckbox(app.GridLayout);
            app.plot_smoothCheckBox.Tooltip = {'1 if want smoothing; 0 if full resolution brain for plotting, KM -take this out now that we downsample brain to do this part'};
            app.plot_smoothCheckBox.Text = 'plot_smooth';
            app.bananCheckBox = uicheckbox(app.GridLayout);
            app.bananCheckBox.Tooltip = {'bananas for subjects'};
            app.bananCheckBox.Text = 'banan';
            app.bananCheckBox.Value = true;
            app.scalpCheckBox = uicheckbox(app.GridLayout);
            app.scalpCheckBox.Tooltip = {'plot scalp for subjects'};
            app.scalpCheckBox.Text = 'scalp';
            app.scalpCheckBox.Value = true;
            app.brainCheckBox = uicheckbox(app.GridLayout);
            app.brainCheckBox.Tooltip = {'plot brain for subjects'};
            app.brainCheckBox.Text = 'brain';
            app.brainCheckBox.Value = true;
            app.isectCheckBox = uicheckbox(app.GridLayout);
            app.isectCheckBox.Tooltip = {'plot banana+brain intersection for each subject'};
            app.isectCheckBox.Text = 'isect';
            app.isectCheckBox.Value = true;
            app.sourcedetCheckBox = uicheckbox(app.GridLayout);
            app.sourcedetCheckBox.Tooltip = {'plot sources and detectors for subjects'};
            app.sourcedetCheckBox.Text = 'sourcedet';
            app.sourcedetCheckBox.Value = true;
            app.savemeanCheckBox = uicheckbox(app.GridLayout);
            app.savemeanCheckBox.Tooltip = {'save a mean bananas over subjects brik'};
            app.savemeanCheckBox.Text = 'savemean';
            app.brainresEditFieldLabel = uilabel(app.GridLayout);
            app.brainresEditFieldLabel.Text = 'brainres';
            app.brainresEditField = uieditfield(app.GridLayout, 'numeric');
            app.brainresEditField.Tooltip = {'resolution of plotting brain, KM - need to add functionality'};
            app.brainresEditField.Value = 2.5;
            app.outresEditFieldLabel = uilabel(app.GridLayout);
            app.outresEditFieldLabel.Text = 'outres';
            app.outresEditField = uieditfield(app.GridLayout, 'numeric');
            app.outresEditField.Tooltip = {'for freesurfer .nii file'};
            app.outresEditField.Value = 0.5;
            app.nsubjEditFieldLabel = uilabel(app.GridLayout);
            app.nsubjEditFieldLabel.Text = 'nsubj';
            app.nsubjEditField = uieditfield(app.GridLayout, 'numeric');
            app.nsubjEditField.Tooltip = {'currently this is from the first how many to count in the .loc file, etc.'};
            app.nsubjEditField.Value = 1;
            app.expEditFieldLabel = uilabel(app.GridLayout);
            app.expEditFieldLabel.Text = 'exp';
            app.expEditField = uieditfield(app.GridLayout, 'text');
            app.expEditField.Tooltip = {'Experiment folder name'; '(normally three letters eg rop)'};
            app.expEditField.Value = 'rop';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Use these settings and run analysis'};
            app.StartButton.Text = 'Start';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.Tooltip = {'load saved settings (not functional)'};
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.Tooltip = {'Save current settings (not functional)'; ''};
            app.SaveButton.Text = 'Save';
            app.SubjectsLabel = uilabel(app.GridLayout);
            app.SubjectsLabel.Text = 'Subjects';
            app.UITable_sub = uitable(app.GridLayout);
            app.UITable_sub.ColumnName = {'sub'};
            app.UITable_sub.RowName = {};
            app.UITable_sub.ColumnEditable = true;
            app.UITable_sub.Tooltip = {'Four digit subject numbers'};
            app.pathEditFieldLabel = uilabel(app.GridLayout);
            app.pathEditFieldLabel.Text = 'path';
            app.pathEditField = uieditfield(app.GridLayout, 'text');
            app.pathEditField.Tooltip = {'data folder'};
            app.pathEditField.Value = '/Volumes/Lab_Files/Data/Backup_Illinois';
            app.UITable_date = uitable(app.GridLayout);
            app.UITable_date.ColumnName = {'date'};
            app.UITable_date.RowName = {};
            app.UITable_date.ColumnEditable = true;
            app.UITable_date.Tooltip = {'6 digit date of MRI scan file MMDDYY'};
            app.dpf2EditField_2Label = uilabel(app.GridLayout);
            app.dpf2EditField_2Label.Text = 'dpf2';
            app.dpf2EditField = uieditfield(app.GridLayout, 'numeric');
            app.dpf2EditField.ValueDisplayFormat = '%.2f';
            app.dpf2EditField.Tooltip = {'Differential path length factor [690 830] per Duncan et al., 95'};
            app.dpf2EditField.Value = 5.86;
            app.dpf1EditFieldLabel = uilabel(app.GridLayout);
            app.dpf1EditFieldLabel.Text = 'dpf1';
            app.dpf1EditField = uieditfield(app.GridLayout, 'numeric');
            app.dpf1EditField.ValueDisplayFormat = '%.2f';
            app.dpf1EditField.Tooltip = {'Differential path length factor [690 830] per Duncan et al., 95'};
            app.dpf1EditField.Value = 6.51;
            app.meansumEditFieldLabel = uilabel(app.GridLayout);
            app.meansumEditFieldLabel.Text = 'meansum';
            app.meansumEditField = uieditfield(app.GridLayout, 'numeric');
            app.meansumEditField.Tooltip = {'plot mean bananas over subjects; 2 = plot sum of bananas over subjects'};
            app.meansumEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mopt3d_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end